classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TimeDomainTab                   matlab.ui.container.Tab
        FolderEditField_2Label          matlab.ui.control.Label
        folder_dir                      matlab.ui.control.EditField
        choose_dir                      matlab.ui.control.Button
        FilenameFormatEditFieldLabel    matlab.ui.control.Label
        FilenameFormatEditField         matlab.ui.control.EditField
        egTXistransmitterRXisreceiverciscountersissuffixLabel  matlab.ui.control.Label
        SaveOptionsLabel                matlab.ui.control.Label
        ProgressEditFieldLabel          matlab.ui.control.Label
        ProgressEditField               matlab.ui.control.NumericEditField
        percent_Label                   matlab.ui.control.Label
        TxAntennaLabel_Matrix           matlab.ui.control.Label
        RxAntennaLabel_Matrix           matlab.ui.control.Label
        AntennaLabel_1                  matlab.ui.control.Label
        AntennaSpinner_TX               matlab.ui.control.Spinner
        SwitchingMatrixLabel            matlab.ui.control.Label
        SetTXBtn                        matlab.ui.control.Button
        Antenna_Label2                  matlab.ui.control.Label
        AntennaSpinner_RX               matlab.ui.control.Spinner
        Switch_2                        matlab.ui.control.Switch
        NbSamples_Label                 matlab.ui.control.Label
        NbSamples_Spinner               matlab.ui.control.Spinner
        StartSample_Label               matlab.ui.control.Label
        StartSample_Spinner             matlab.ui.control.Spinner
        AutomationLabel                 matlab.ui.control.Label
        start_btn                       matlab.ui.control.Button
        PS9201ATab                      matlab.ui.container.Tab
        PS9201ALabel                    matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        ConfigureButton                 matlab.ui.control.Button
        ConfigFileButton                matlab.ui.control.Button
        ConfigurationFileEditFieldLabel  matlab.ui.control.Label
        ConfigurationFileEditField      matlab.ui.control.EditField
        SampleNumberEditFieldLabel      matlab.ui.control.Label
        SampleNumberEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        AverageNumberEditFieldLabel     matlab.ui.control.Label
        AverageNumberEditField          matlab.ui.control.NumericEditField
        GridScalingmVdivEditFieldLabel  matlab.ui.control.Label
        GridScalingmVdivEditField       matlab.ui.control.NumericEditField
        ToconnectthepiscoscopeclickontheconnectbuttonbelowLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        getButton                       matlab.ui.control.Button
    end

    
    properties (Access = private) % create new private property in order to connect picoscope to GUI
        a % Arbitrary variable
        MCU % MCU
        % File name and path name of configuration files for picsocope
        conf_fileName
        conf_pathName
        % File name and path name for output text files
        txt_fileName
        txt_pathName
    end
    

    methods (Access = private)

        % Button pushed function: choose_dir
        function choose_dirPushed(app, event)
            % Choose location of text file to save data in
            %selpath = uigetdir('P:\'); figure(app.UIFigure);
            selpath = uigetdir("P:\","Choose Folder");
            %setting "figure" to UIFigure because for some reason if this is not done the window is minimized when you press cancel
            
            %If user clicks on cancel, stop operation
            if selpath==0
                return;
            else
               %Changes the text value to according path
               app.folder_dir.Value = selpath;
            end
           
        end

        % Value changed function: folder_dir
        function folder_dirValueChanged(app, event)
            value = app.folder_dir.Value;
            
        end

        % Value changing function: folder_dir
        function folder_dirValueChanging(app, event)
            changingValue = event.Value;
          
            
        end

        % Button pushed function: ConfigFileButton
        function ConfigFileButtonPushed(app, event)
            %get file needed
            [file,path]= uigetfile('*.txt','Select File'); figure(app.UIFigure);
            %setting "figure" to UIFigure because for some reason if this is not done the window is minimized when you press cancel
             app.conf_fileName = file;
             app.conf_pathName = path;
            
           
            app.ConfigurationFileEditField.Value = strcat(app.conf_pathName,app.conf_fileName);
        end

        % Callback function: ConfigurationFileEditField, 
        % ConfigurationFileEditField
        function ConfigurationFileEditFieldValueChanging(app, event)
          app.ConfigurationFileEditField.Value = strcat(app.conf_pathName)
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
%             jBarHandle = javax.swing.JProgressBar(0, 103);
%             jBarHandle.setStringPainted(true);
%             jBarHandle.setIndeterminate(false);
%            
%             fig = figure('Position', [0 0 200 20]);
%             [jhandle, hhandle] = javacomponent(jBarHandle, [0 0 1 1], fig);
%             set(hhandle, 'parent', fig, 'Units', 'norm', 'Position', [0 0 1 1])
%             as
%             for k = 1:103
%                 javaMethodEDT('setValue', jBarHandle, k);
%                 pause(0.1);
%             end
            

                %Read data from selected text file
                s={};
                file=fullfile(app.conf_pathName,app.conf_fileName)
                fid = fopen(file); 
                tline = fgetl(fid); 
                while ischar(tline) 
                    s=[s;tline]; 
                    tline = fgetl(fid)
                   
                    %Send to picoscope
                    app.a.ExecCommand(tline)

                end 
            
        end

        % Button pushed function: ConnectButton
        function pico_connect_btn(app, event)
            %Want to connect the GUI to the Picoscope with the click of a button.
            %% Use the function we found online in order to do so
            % Try to see if it is compatble with our model of the picoscope 
            % If not, we're fucked...
                % Make hardware compatible
            % Then configure the "configuration" button. 
            % Need a windows computer to make thigs work
           
            % To connect the picsocope, matlab has to establish connection with it
           % ps9201aDeviceObj = icdevice('picotech_ps9201a_generic', 'YMS02008');
           
            % Creates object to hold PicoScope 9200 
            % app.a = actxserver('PicoScope9000.COMRC');
          
            % picoscope.com /a Measurements.CSV?
            try
                app.a = actxserver('PicoScope9000.COMRC');


            catch
                errordlg('Picoscope connection failed');
            end
   
            
        end

        % Button pushed function: getButton
        function getButtonPushed(app, event)
           % First turn off header stop word from appearing when we only need numbers
           app.a.ExecCommand('Header Off');
        
            % Please note that all value returned when calling a command is a string
        
            % These are the values they are returned from the command, but returned as a
            % string with comma separated variables
            Y = str2double(strsplit(app.a.ExecCommand('wfm:Data?'),','));
            assignin('base','values', Y);
        
            % This gives the initial value of x
            X_start = str2double(app.a.ExecCommand('Wfm:Preamb:XOrg?'));
            assignin('base','timeStart', X_start);
        
            % This gives the increment value of x
            X_increment = str2double(app.a.ExecCommand('Wfm:Preamb:XInc?'));
            assignin('base','timeIncrement', X_increment);
        
            % This gives the units of the Y axis
            Y_unit=app.a.ExecCommand('Wfm:Preamb:YU?');
            assignin('base','valuesUnit', Y_unit);
        
            % This gives the units of the X axis
            X_unit=app.a.ExecCommand('Wfm:Preamb:XU?');
            assignin('base','timeUnits', X_unit);
        
            % Creating the variables to plot them
            no_points = size(Y,2);
            X= X_start:X_increment:(X_increment*(no_points-1)+X_start);
            
            fig = app.UIFigure;
            ax = app.UIAxes;
            plot(ax,X,Y, '.');
            app.UIAxes.XLabel(['Time in ' X_unit]);
            app.UIAxes.YLabel(['Values in ' Y_unit]);
              
%             plot(X,Y, '.');
%             xlabel(['Time in ' X_unit]);
%             ylabel(['Values in ' Y_unit]);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            ConState = string(app.Switch_2.Value);
            fprintf(ConState)
            if ConState == 'On'
                delete(instrfind('Port','COM3')); % delete any existing connection first
                
                app.MCU = serial('COM3','BaudRate',9600,'FlowControl','none','ByteOrder','littleEndian','Timeout',20,'Terminator',{'CR', 'CR'});            
                fopen(app.MCU); % open serial port connection
               fprintf(app.MCU, '1')
                get(app.MCU)
                fprintf('if  \n')
            elseif ConState == 'Disconnect'
                delete(instrfind('Port','COM3'));
                fprintf('else \n')
            end 
            fprintf('job done \n')
        end

        % Button pushed function: SetTXBtn
        function SetTXBtnButtonPushed(app, event)
             ConState = string(app.Switch_2.Value);
             if ConState == 'On'
                 set_SMC_command_str = "SMC_SET_TX%01d_RX%02d"
                 
                 % Convert Antenna number to string for future use
                 TX = num2str(app.AntennaSpinner_TX.Value);
                 RX = num2str(app.AntennaSpinner_RX.Value);
                 
                 old_TX = "%01d";
                 old_RX = "%02d";
                 sprintf(TX)
                 Command = replace(set_SMC_command_str,old_TX,TX)
                 Final_Command = replace(Command,old_RX,RX)
                 
                 fprintf(Final_Command)
                 fprintf(app.MCU, Final_Command);
                 
                 fgets(app.MCU)


             end 
  
        end

        % Button pushed function: start_btn
        function start_btnButtonPushed(app, event)
            % Get dir path
            txt_pathName = app.folder_dir.Value;  
            % Get number of samples
            i = app.NbSamples_Spinner.Value;
            
            
            % fix antenna numbers in filename : sigi_%TX_%RX%.txt
            filename_tmp = app.FilenameFormatEditField.Value;
            TX = num2str(app.AntennaSpinner_TX.Value);
            RX = num2str(app.AntennaSpinner_RX.Value);
            old_TX = "%TX";
            old_RX = "%RX%";
            sample_nb = "n";
            filename_tmp = replace(filename_tmp,old_TX,TX);
            filename_tmp = replace(filename_tmp,old_RX,RX);     
            txt_fileName = filename_tmp ;  
            
            if TX == RX
                    warningMessage = sprintf('Warning: Cannot have same antenna number for Tx and Rx');
                    uiwait(msgbox(warningMessage));
                return
            end
            
     
            for c = 1:i
                % pathName/fileName
                iteration_nb = num2str(c);
%                 fprintf(iteration_nb);
                txt_fileName = filename_tmp;
                txt_fileName = replace(txt_fileName,sample_nb,iteration_nb)
%                 fprintf(filename_tmp); 
                file = [txt_pathName filesep txt_fileName] 
                
                % Check if filename already exists
                if isfile(file)
                    % File exists.
                    warningMessage = sprintf('Warning: file already exists:\n%s', txt_fileName);
                    uiwait(msgbox(warningMessage));
                else
                    % File does not exist: Save file
                    K = rand(10) ;  % some random data to write in file 
                    save(file,'K','-ascii')
                end
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.FolderEditField_2Label = uilabel(app.TimeDomainTab);
            app.FolderEditField_2Label.Text = 'Folder:';
            app.folder_dir = uieditfield(app.TimeDomainTab, 'text');
            app.folder_dir.ValueChangedFcn = createCallbackFcn(app, @folder_dirValueChanged, true);
            app.folder_dir.ValueChangingFcn = createCallbackFcn(app, @folder_dirValueChanging, true);
            app.folder_dir.Value = 'C:\Users\Experiments\Desktop\Temporary';
            app.choose_dir = uibutton(app.TimeDomainTab, 'push');
            app.choose_dir.ButtonPushedFcn = createCallbackFcn(app, @choose_dirPushed, true);
            app.choose_dir.Text = '...';
            app.FilenameFormatEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FilenameFormatEditFieldLabel.Text = 'File name Format';
            app.FilenameFormatEditField = uieditfield(app.TimeDomainTab, 'text');
            app.FilenameFormatEditField.Value = 'sign_%TX_%RX%.txt';
            app.egTXistransmitterRXisreceiverciscountersissuffixLabel = uilabel(app.TimeDomainTab);
            app.egTXistransmitterRXisreceiverciscountersissuffixLabel.Text = 'e.g. %TX% is transmitter, %RX% is receiver// %c% is counter, %s% is suffix';
            app.SaveOptionsLabel = uilabel(app.TimeDomainTab);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.ProgressEditFieldLabel = uilabel(app.TimeDomainTab);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.ProgressEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.percent_Label = uilabel(app.TimeDomainTab);
            app.percent_Label.Text = '%';
            app.TxAntennaLabel_Matrix = uilabel(app.TimeDomainTab);
            app.TxAntennaLabel_Matrix.Text = 'Tx Antenna';
            app.RxAntennaLabel_Matrix = uilabel(app.TimeDomainTab);
            app.RxAntennaLabel_Matrix.Text = 'Rx Antenna';
            app.AntennaLabel_1 = uilabel(app.TimeDomainTab);
            app.AntennaLabel_1.Text = 'Antenna #:';
            app.AntennaSpinner_TX = uispinner(app.TimeDomainTab);
            app.AntennaSpinner_TX.Value = 1;
            app.SwitchingMatrixLabel = uilabel(app.TimeDomainTab);
            app.SwitchingMatrixLabel.Text = 'Switching Matrix';
            app.SetTXBtn = uibutton(app.TimeDomainTab, 'push');
            app.SetTXBtn.ButtonPushedFcn = createCallbackFcn(app, @SetTXBtnButtonPushed, true);
            app.SetTXBtn.Text = 'Set';
            app.Antenna_Label2 = uilabel(app.TimeDomainTab);
            app.Antenna_Label2.Text = 'Antenna #:';
            app.AntennaSpinner_RX = uispinner(app.TimeDomainTab);
            app.AntennaSpinner_RX.Value = 1;
            app.Switch_2 = uiswitch(app.TimeDomainTab, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.NbSamples_Label = uilabel(app.TimeDomainTab);
            app.NbSamples_Label.Text = 'Number of Cases';
            app.NbSamples_Spinner = uispinner(app.TimeDomainTab);
            app.NbSamples_Spinner.Value = 1;
            app.StartSample_Label = uilabel(app.TimeDomainTab);
            app.StartSample_Label.Text = 'Starts at Case #';
            app.StartSample_Spinner = uispinner(app.TimeDomainTab);
            app.StartSample_Spinner.Value = 1;
            app.start_btn = uibutton(app.TimeDomainTab, 'push');
            app.start_btn.ButtonPushedFcn = createCallbackFcn(app, @start_btnButtonPushed, true);
            app.start_btn.Text = 'Start';
            app.AutomationLabel = uilabel(app.TimeDomainTab);
            app.AutomationLabel.Text = 'Automation';
            app.PS9201ATab = uitab(app.TabGroup);
            app.PS9201ATab.Title = 'PS9201A';
            app.PS9201ALabel = uilabel(app.PS9201ATab);
            app.PS9201ALabel.Text = 'PS9201A';
            app.ConnectButton = uibutton(app.PS9201ATab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @pico_connect_btn, true);
            app.ConnectButton.Text = 'Connect';
            app.ConfigureButton = uibutton(app.PS9201ATab, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.ConfigFileButton = uibutton(app.PS9201ATab, 'push');
            app.ConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigFileButtonPushed, true);
            app.ConfigFileButton.Text = '...';
            app.ConfigurationFileEditFieldLabel = uilabel(app.PS9201ATab);
            app.ConfigurationFileEditFieldLabel.Text = 'Configuration File';
            app.ConfigurationFileEditField = uieditfield(app.PS9201ATab, 'text');
            app.ConfigurationFileEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigurationFileEditFieldValueChanging, true);
            app.ConfigurationFileEditField.ValueChangingFcn = createCallbackFcn(app, @ConfigurationFileEditFieldValueChanging, true);
            app.ConfigurationFileEditField.Value = 'C:\Users\Undergrad2019\Downloads\TomoDAQ.20181129\Configuration Files\config_16avg.txt';
            app.SampleNumberEditFieldLabel = uilabel(app.PS9201ATab);
            app.SampleNumberEditFieldLabel.Text = 'Sample Number';
            app.SampleNumberEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.SamplingRateEditFieldLabel = uilabel(app.PS9201ATab);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.AverageNumberEditFieldLabel = uilabel(app.PS9201ATab);
            app.AverageNumberEditFieldLabel.Text = 'Average Number';
            app.AverageNumberEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.GridScalingmVdivEditFieldLabel = uilabel(app.PS9201ATab);
            app.GridScalingmVdivEditFieldLabel.Text = 'Grid Scaling (mV/div)';
            app.GridScalingmVdivEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.ToconnectthepiscoscopeclickontheconnectbuttonbelowLabel = uilabel(app.PS9201ATab);
            app.ToconnectthepiscoscopeclickontheconnectbuttonbelowLabel.Text = 'To connect the piscoscope, click on the connect button below.';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.getButton = uibutton(app.UIFigure, 'push');
            app.getButton.ButtonPushedFcn = createCallbackFcn(app, @getButtonPushed, true);
            app.getButton.Text = 'Get';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end