classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TimeDomainTab                 matlab.ui.container.Tab
        FolderEditField_2Label        matlab.ui.control.Label
        FolderEditField_2             matlab.ui.control.EditField
        Button_2                      matlab.ui.control.Button
        FilenameFormatEditFieldLabel  matlab.ui.control.Label
        FilenameFormatEditField       matlab.ui.control.EditField
        egTXistransmitterRXisreceiverciscountersissuffixLabel  matlab.ui.control.Label
        AveragingLabel                matlab.ui.control.Label
        SamplingRateGHzSpinnerLabel   matlab.ui.control.Label
        SamplingRateGHzSpinner        matlab.ui.control.Spinner
        SaveOptionsLabel              matlab.ui.control.Label
        PS9201ATab                    matlab.ui.container.Tab
        PS9201ALabel                  matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        ConfigureButton               matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        ConfigurationFileEditFieldLabel  matlab.ui.control.Label
        ConfigurationFileEditField    matlab.ui.control.EditField
        SwitchingMatrixTab            matlab.ui.container.Tab
        TxAntennaLabel                matlab.ui.control.Label
        RxAntennaLabel                matlab.ui.control.Label
        AntennaSpinnerLabel           matlab.ui.control.Label
        AntennaSpinner                matlab.ui.control.Spinner
        TomoBraControlLabel           matlab.ui.control.Label
        SetButton                     matlab.ui.control.Button
        LoopDelaymsSpinnerLabel       matlab.ui.control.Label
        LoopDelaymsSpinner            matlab.ui.control.Spinner
        LoopButton                    matlab.ui.control.Button
        SetButton_2                   matlab.ui.control.Button
        LoopButton_2                  matlab.ui.control.Button
        AntennaSpinner_2Label         matlab.ui.control.Label
        AntennaSpinner_2              matlab.ui.control.Spinner
        LoopDelaymsSpinner_2Label     matlab.ui.control.Label
        LoopDelaymsSpinner_2          matlab.ui.control.Spinner
        Switch                        matlab.ui.control.Switch
        UIAxes                        matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Choose locatio of text file to save data in and name it
            [file,path]= uiputfile('*.txt','Select a File')
            if isequal(file,0) || isequal(path,0)
               disp('User clicked Cancel.')
            else
               disp(['User selected ',fullfile(path,file),...
                     ' and then clicked Save.'])
            end
            %Changes the text value to according path
              app.FolderEditField_2.Value = path;
        end

        % Value changed function: FolderEditField_2
        function FolderEditField_2ValueChanged(app, event)
            value = app.FolderEditField_2.Value;
            
        end

        % Value changing function: FolderEditField_2
        function FolderEditField_2ValueChanging(app, event)
            changingValue = event.Value;
          
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %get file needed
            [file,path]= uigetfile('*.txt','Select File')
            %Read data from selected text file
            %s={};
            %file=fullfile(PathName,FileName)
            %fid = fopen(file); 
            %tline = fgetl(fid); 
            %while ischar(tline) 
               %s=[s;tline]; 
               %tline = fgetl(fid); 
            %end    
            app.ConfigurationFileEditField.Value = path;
        end

        % Callback function: ConfigurationFileEditField
        function ConfigurationFileEditFieldValueChanging(app, event)
          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.FolderEditField_2Label = uilabel(app.TimeDomainTab);
            app.FolderEditField_2Label.Text = 'Folder:';
            app.FolderEditField_2 = uieditfield(app.TimeDomainTab, 'text');
            app.FolderEditField_2.ValueChangedFcn = createCallbackFcn(app, @FolderEditField_2ValueChanged, true);
            app.FolderEditField_2.ValueChangingFcn = createCallbackFcn(app, @FolderEditField_2ValueChanging, true);
            app.FolderEditField_2.Value = 'C:\Users\Experiments\Desktop\Temporary';
            app.Button_2 = uibutton(app.TimeDomainTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.FilenameFormatEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FilenameFormatEditFieldLabel.Text = 'File name Format';
            app.FilenameFormatEditField = uieditfield(app.TimeDomainTab, 'text');
            app.FilenameFormatEditField.Value = 'sig_%TX_%RX%.txt';
            app.egTXistransmitterRXisreceiverciscountersissuffixLabel = uilabel(app.TimeDomainTab);
            app.egTXistransmitterRXisreceiverciscountersissuffixLabel.Text = 'e.g. %TX% is transmitter, %RX% is receiver// %c% is counter, %s% is suffix';
            app.AveragingLabel = uilabel(app.TimeDomainTab);
            app.AveragingLabel.Text = 'Averaging';
            app.SamplingRateGHzSpinnerLabel = uilabel(app.TimeDomainTab);
            app.SamplingRateGHzSpinnerLabel.Text = 'Sampling Rate (GHz)';
            app.SamplingRateGHzSpinner = uispinner(app.TimeDomainTab);
            app.SamplingRateGHzSpinner.Value = 80;
            app.SaveOptionsLabel = uilabel(app.TimeDomainTab);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.PS9201ATab = uitab(app.TabGroup);
            app.PS9201ATab.Title = 'PS9201A';
            app.PS9201ALabel = uilabel(app.PS9201ATab);
            app.PS9201ALabel.Text = 'PS9201A';
            app.ConnectButton = uibutton(app.PS9201ATab, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConfigureButton = uibutton(app.PS9201ATab, 'push');
            app.ConfigureButton.Text = 'Configure';
            app.Button_3 = uibutton(app.PS9201ATab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.ConfigurationFileEditFieldLabel = uilabel(app.PS9201ATab);
            app.ConfigurationFileEditFieldLabel.Text = 'Configuration File';
            app.ConfigurationFileEditField = uieditfield(app.PS9201ATab, 'text');
            app.ConfigurationFileEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigurationFileEditFieldValueChanging, true);
            app.ConfigurationFileEditField.ValueChangingFcn = createCallbackFcn(app, @ConfigurationFileEditFieldValueChanging, true);
            app.ConfigurationFileEditField.Value = 'C:\Users\Experiments\Desktop\Temporary';
            app.SwitchingMatrixTab = uitab(app.TabGroup);
            app.SwitchingMatrixTab.Title = 'Switching Matrix';
            app.TxAntennaLabel = uilabel(app.SwitchingMatrixTab);
            app.TxAntennaLabel.Text = 'Tx Antenna';
            app.RxAntennaLabel = uilabel(app.SwitchingMatrixTab);
            app.RxAntennaLabel.Text = 'Rx Antenna';
            app.AntennaSpinnerLabel = uilabel(app.SwitchingMatrixTab);
            app.AntennaSpinnerLabel.Text = 'Antenna #:';
            app.AntennaSpinner = uispinner(app.SwitchingMatrixTab);
            app.AntennaSpinner.Value = 1;
            app.TomoBraControlLabel = uilabel(app.SwitchingMatrixTab);
            app.TomoBraControlLabel.Text = 'TomoBra Control';
            app.SetButton = uibutton(app.SwitchingMatrixTab, 'push');
            app.SetButton.Text = 'Set';
            app.LoopDelaymsSpinnerLabel = uilabel(app.SwitchingMatrixTab);
            app.LoopDelaymsSpinnerLabel.Text = 'Loop Delay (ms) :';
            app.LoopDelaymsSpinner = uispinner(app.SwitchingMatrixTab);
            app.LoopDelaymsSpinner.Value = 1000;
            app.LoopButton = uibutton(app.SwitchingMatrixTab, 'push');
            app.LoopButton.Text = 'Loop';
            app.SetButton_2 = uibutton(app.SwitchingMatrixTab, 'push');
            app.SetButton_2.Text = 'Set';
            app.LoopButton_2 = uibutton(app.SwitchingMatrixTab, 'push');
            app.LoopButton_2.Text = 'Loop';
            app.AntennaSpinner_2Label = uilabel(app.SwitchingMatrixTab);
            app.AntennaSpinner_2Label.Text = 'Antenna #:';
            app.AntennaSpinner_2 = uispinner(app.SwitchingMatrixTab);
            app.AntennaSpinner_2.Value = 1;
            app.LoopDelaymsSpinner_2Label = uilabel(app.SwitchingMatrixTab);
            app.LoopDelaymsSpinner_2Label.Text = 'Loop Delay (ms) :';
            app.LoopDelaymsSpinner_2 = uispinner(app.SwitchingMatrixTab);
            app.LoopDelaymsSpinner_2.Value = 1000;
            app.Switch = uiswitch(app.SwitchingMatrixTab, 'slider');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end